classdef MultiMosaic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadRawDataMenu             matlab.ui.container.Menu
        FromDirectoryMenu           matlab.ui.container.Menu
        FromFileMenu                matlab.ui.container.Menu
        FromHyperViewerMenu         matlab.ui.container.Menu
        LoadMosaicPathMenu          matlab.ui.container.Menu
        LoadMosaicImageMenu         matlab.ui.container.Menu
        SaveRawDataash5Menu         matlab.ui.container.Menu
        SaveMosaicPathMenu          matlab.ui.container.Menu
        SaveMosaicImageMenu         matlab.ui.container.Menu
        RegistrationMenu            matlab.ui.container.Menu
        CropDataMenu                matlab.ui.container.Menu
        FlatfieldCorrectionMenu     matlab.ui.container.Menu
        AlignmentKernelMenu         matlab.ui.container.Menu
        XCorrelationMenu            matlab.ui.container.Menu
        SURFFeatureRefinedMenu      matlab.ui.container.Menu
        SURFMLESACMenu              matlab.ui.container.Menu
        MatrixTypeMenu              matlab.ui.container.Menu
        TranslationMenu             matlab.ui.container.Menu
        SimilarityMenu              matlab.ui.container.Menu
        AffineMenu                  matlab.ui.container.Menu
        CalculateMenu               matlab.ui.container.Menu
        AlignmentMenu               matlab.ui.container.Menu
        FramePairViewMenu           matlab.ui.container.Menu
        OverlayMenu                 matlab.ui.container.Menu
        MontageMenu                 matlab.ui.container.Menu
        ShowMatchedFeaturesMenu     matlab.ui.container.Menu
        AutoFixAlignmentsMenu       matlab.ui.container.Menu
        ManualMatchMenu             matlab.ui.container.Menu
        ThisFramePairMenu           matlab.ui.container.Menu
        AllFramePairsMenu           matlab.ui.container.Menu
        StitchingMenu               matlab.ui.container.Menu
        ViewMenu                    matlab.ui.container.Menu
        PathMenu                    matlab.ui.container.Menu
        MosaicMenu                  matlab.ui.container.Menu
        StitchingTypeMenu           matlab.ui.container.Menu
        GraphCutMenu                matlab.ui.container.Menu
        LayeringMenu                matlab.ui.container.Menu
        StitchMosaicMenu            matlab.ui.container.Menu
        TrackingMenu                matlab.ui.container.Menu
        TrackingParametersMenu      matlab.ui.container.Menu
        FindaRandomFrameMenu        matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        DataandRegistrationTab      matlab.ui.container.Tab
        FrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel         matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        RegistrationImage           matlab.ui.control.UIAxes
        AlignmentEditorTab          matlab.ui.container.Tab
        TraceEditField              matlab.ui.control.NumericEditField
        TraceEditFieldLabel         matlab.ui.control.Label
        NmatchesEditField           matlab.ui.control.NumericEditField
        NmatchesEditFieldLabel      matlab.ui.control.Label
        ReCalculateTransformButton  matlab.ui.control.Button
        ShowMatchedFeaturesButton   matlab.ui.control.Button
        TransformMatrixLabel        matlab.ui.control.Label
        AffineMatrixViewer          matlab.ui.control.Table
        TformEditField              matlab.ui.control.NumericEditField
        TformEditFieldLabel         matlab.ui.control.Label
        TformSlider                 matlab.ui.control.Slider
        MatchDensityViewer          matlab.ui.control.UIAxes
        FramePairViewer             matlab.ui.control.UIAxes
        TransformViewer             matlab.ui.control.UIAxes
        StitchingandTrackingTab     matlab.ui.container.Tab
        MosaicImage                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        VideoParameters;
        VideoFrames;
        VideoFramesComposites;
        VideoFramesGPU;
        MosaickingParameters;
        Mosaic;      
        Tracking;
    end
    
    methods (Access = private) % Enable when we load raw data. Allow calculating Tforms, frame slider,          
        function registrationEnable(app, bool)
            app.FrameSlider.Enable = bool; 
            app.FrameEditField.Enable = bool;
            app.RegistrationMenu.Enable = bool;
        end 
        
        function tformViewerEnable(app, bool)
            app.TformSlider.Enable = bool;
            app.TformEditField.Enable = bool;
            app.AlignmentMenu.Enable = bool;            
        end
        
        function stitchingEnable(app, bool)
            app.StitchingMenu.Enable = bool; 
        end
        
        function trackingEnable(app, bool)
            app.TrackingMenu.Enable = bool;
            pastMatches = cell(size(app.Mosaic.Composite, 3), 2);
            mosaicSmall = imresize(app.Mosaic.Composite,app.Tracking.Scale,'nearest');
            for i = 1:size(app.Mosaic.Composite, 3)
                mosaicSURF = detectSURFFeatures(mosaicSmall(:,:,i));
                [features1, validpoints1] = extractFeatures(mosaicSmall(:,:,i), mosaicSURF, 'Upright', false);    
                pastMatches{i,1} = features1; 
                pastMatches{i,2} = validpoints1;
            end
            app.Tracking.MosaicSURFPoints = pastMatches;
        end
        
        function drawFrame(app, axis, frameID) % TODO: Change to update Cdata for better speed
            cla(axis);
            imshow(app.VideoFramesComposites(:,:,:,frameID),'Parent', axis);
        end
        
        function mosaicInit(app)
            stitchInit(app);
            app.Mosaic.Tforms(1:app.VideoParameters.nframes) = affine2d;            
        end
        
        function dataSizeInit(app)
            app.VideoParameters.nframes = size(app.VideoFrames, 4);
            app.VideoParameters.yRes = size(app.VideoFrames, 1);
            app.VideoParameters.xRes = size(app.VideoFrames, 2);                  
            app.FrameSlider.Limits = [1, app.VideoParameters.nframes];
            app.FrameSlider.Value = 1;        
        end
        
        function drawTform(app, tform)
            x = [0 app.VideoParameters.xRes app.VideoParameters.xRes 0];
            y = [0 0 app.VideoParameters.yRes app.VideoParameters.yRes];
            [xt,yt] = transformPointsForward(tform,x,y);
            cla(app.TransformViewer);
            hold(app.TransformViewer, 'on');
            plot(app.TransformViewer,x,y,'k'); fill(app.TransformViewer,x,y,'k'); alpha(app.TransformViewer,0.5);
            plot(app.TransformViewer,xt,yt,'k'); fill(app.TransformViewer,xt,yt,'r'); alpha(app.TransformViewer,0.5);
            hold(app.TransformViewer, 'off');            
            app.AffineMatrixViewer.Data =  tform.T; 
            app.TraceEditField.Value = trace(double(tform.T));
        end      

        
        function drawTransform(app, localtform, pts1, pts2, dyGood, dxGood, dMap)            
            drawTform(app, localtform);
            hold(app.TransformViewer, 'on');
            plot(app.TransformViewer, [pts1.Location(:,1) pts2.Location(:,1)]', [pts1.Location(:,2) pts2.Location(:,2)]', 'LineWidth', 2);
            hold(app.TransformViewer, 'off');
            
            cla(app.FramePairViewer); cla(app.MatchDensityViewer);
            
            if strcmp(app.MontageMenu.Checked,'on')
                showMatchedFeatures(app.VideoFramesComposites(:,:,:,round(app.TformEditField.Value))...
                ,app.VideoFramesComposites(:,:,:,round(app.TformEditField.Value)+1)...
                ,pts1,pts2,'montage','Parent',app.FramePairViewer);
            elseif strcmp(app.OverlayMenu.Checked,'on')
                showMatchedFeatures(rgb2gray(app.VideoFramesComposites(:,:,:,round(app.TformEditField.Value)))...
                ,rgb2gray(app.VideoFramesComposites(:,:,:,round(app.TformEditField.Value)+1))...
                ,pts1,pts2,'falsecolor','Parent',app.FramePairViewer);     
            end
            
            hold(app.MatchDensityViewer, 'on');
            imagesc(app.MatchDensityViewer, [-(app.VideoParameters.xRes-1) (app.VideoParameters.xRes)],...
                [-(app.VideoParameters.yRes-1) (app.VideoParameters.yRes)], dMap);
            app.Mosaic.roi = images.roi.Rectangle(app.MatchDensityViewer, 'Deletable', false, 'FixedAspectRatio', true, 'Color', 'r', 'Position', ...
                [dxGood(1) - app.MosaickingParameters.FeatureSpreadTolerance/2 dyGood(1) - app.MosaickingParameters.FeatureSpreadTolerance/2 ...
                app.MosaickingParameters.FeatureSpreadTolerance app.MosaickingParameters.FeatureSpreadTolerance]);
            hold(app.MatchDensityViewer, 'off');            
        end
        
        
        function stitchInit(app) % TODO: Delete this function? Seems uneccesary
            app.Mosaic.Composite = zeros(app.VideoParameters.yRes, app.VideoParameters.xRes, 3);    
        end        
        
        function gpuInit(app)
            try
                canUseGPU = parallel.gpu.GPUDevice.isAvailable;
            catch
                canUseGPU = false;
            end
            if canUseGPU
                app.MosaickingParameters.CanUseGPU = true;
                app.VideoFramesGPU = gpuArray(single(app.VideoFrames));
            else
                app.VideoFramesGPU = single(app.VideoFrames);
            end            
        end
        
        function tformValueChanged(app) % TODO: Change to drawing a montage of frames i and i+1. Change to updating Cdata for speed.
            localtform = app.Mosaic.Tforms(app.TformEditField.Value);
            drawTform(app, localtform);       
            montage({app.VideoFramesComposites(:,:,:,app.TformEditField.Value),app.VideoFramesComposites(:,:,:,app.TformEditField.Value+1)},...
                'Size', [1 2],'Parent', app.FramePairViewer);
            try
                app.NmatchesEditField.Value = app.Mosaic.Nmatches(app.TformEditField.Value);
            catch
            end
        end
        
        function translationSelected(app)
            app.TranslationMenu.Checked = true;
            app.SimilarityMenu.Checked = false;
            app.AffineMenu.Checked = false;
            app.MosaickingParameters.matrixtype = 'Translation';
        end
        
        function similaritySelected(app)
            app.TranslationMenu.Checked = false;
            app.SimilarityMenu.Checked = true;
            app.AffineMenu.Checked = false;
            app.MosaickingParameters.matrixtype = 'Similarity';
        end
        
        function affineSelected(app)
            app.TranslationMenu.Checked = false;
            app.SimilarityMenu.Checked = false;
            app.AffineMenu.Checked = true;
            app.MosaickingParameters.matrixtype = 'Affine';
        end
        
        function graphMosaicPath(app)
            % Get the dx dy points from the transform
            points = zeros([length(app.Mosaic.Tforms) 2]);
            for i = 1:length(app.Mosaic.Tforms)
                points(i, :) = app.Mosaic.Tforms(i).T(3, 1:2);
                if i > 1
                    points (i, :) = points(i, :) + points(i-1, :);
                end
            end
            app.MosaicImage.YDir = 'reverse';
            plot(app.MosaicImage, points(:, 1), points(:, 2), '-o');        
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.RegistrationImage.Visible = 'off';
            app.RegistrationImage.Colormap = gray(256);
            axis(app.RegistrationImage, 'image');
            app.MosaickingParameters.CanUseGPU = false;
            app.MosaickingParameters.FeatureSpreadTolerance = 10;
            app.AffineMatrixViewer.Data = eye(3);
            registrationEnable(app, 0);
            tformViewerEnable(app, 0);
            stitchingEnable(app, 0);
            app.TrackingMenu.Enable = 0;
            app.Tracking.Scale = 0.3; 
            app.Tracking.Tol = 30;
            app.MosaickingParameters.matrixtype = 'Translation';
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            drawFrame(app, app.RegistrationImage, round(event.Value));
            app.FrameEditField.Value = round(event.Value);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            drawFrame(app, app.RegistrationImage, round(app.FrameEditField.Value));
            app.FrameSlider.Value = app.FrameEditField.Value;
        end

        % Value changing function: TformSlider
        function TformSliderValueChanging(app, event)
            app.TformEditField.Value = round(event.Value);
            tformValueChanged(app);            
            app.ReCalculateTransformButton.Enable = 0; 
        end

        % Value changed function: TformEditField
        function TformEditFieldValueChanged(app, event)
            app.TformEditField.Value = round(event.Value);     
            tformValueChanged(app); 
            app.TformSlider.Value = round(app.TformEditField.Value);            
            app.ReCalculateTransformButton.Enable = 0; 
        end

        % Button pushed function: ShowMatchedFeaturesButton
        function ShowMatchedFeaturesButtonPushed(app, event)
            [~,~,~,matches1,matches2,dMap] = mchannel_SURF_reg(...
                    app.VideoFrames(:,:,:,round(app.TformEditField.Value)), ...
                    app.VideoFrames(:,:,:,round(app.TformEditField.Value)+1)); ...
            
            [pts1,pts2] = refineMatches(matches1, matches2, app.Mosaic.dydxGood(app.TformEditField.Value,1),...
                app.Mosaic.dydxGood(app.TformEditField.Value, 2), app.MosaickingParameters.FeatureSpreadTolerance);
                
            drawTransform(app, app.Mosaic.Tforms(app.TformEditField.Value), pts1, pts2, app.Mosaic.dydxGood(app.TformEditField.Value,1), ...
                app.Mosaic.dydxGood(app.TformEditField.Value, 2), dMap);
            
            app.NmatchesEditField.Value = length(pts1);
            app.Mosaic.Nmatches(app.TformEditField.Value) = app.NmatchesEditField.Value;
            
            hold(app.MatchDensityViewer, 'on');
            scatter(app.MatchDensityViewer, app.Mosaic.dydxCorr(app.TformEditField.Value,2), app.Mosaic.dydxCorr(app.TformEditField.Value,1), 50, 'red', 'filled');
            if app.TformEditField.Value > 1
                scatter(app.MatchDensityViewer, app.Mosaic.dydxTform(app.TformEditField.Value-1,2), app.Mosaic.dydxTform(app.TformEditField.Value-1,1), 50, 'k', 'filled');
            end
            if isfield(app.Mosaic, 'dydxSURFs') == 1
                scatter(app.MatchDensityViewer, squeeze(app.Mosaic.dydxSURFs(app.TformEditField.Value,2,:)), squeeze(app.Mosaic.dydxSURFs(app.TformEditField.Value,1,:)), 50, 'm', 'filled');            
            end
            hold(app.MatchDensityViewer, 'off');            
            app.ReCalculateTransformButton.Enable = 1;           
        end

        % Button pushed function: ReCalculateTransformButton
        function ReCalculateTransformButtonPushed(app, event)
            % Re-calculate transform app.TformEditField.Value based on app.Mosaic.roi
            pos = app.Mosaic.roi.Position;            
            dxGood = pos(1) + pos(3)/2; dyGood = pos(2) + pos(3)/2;   
            [~,~,~,matches1, matches2, dMap] = mchannel_SURF_reg(...
                    app.VideoFrames(:,:,:,round(app.TformEditField.Value)), ...
                    app.VideoFrames(:,:,:,round(app.TformEditField.Value)+1), ...
                    dyGood, dxGood);
            [pts1,pts2] = refineMatches(matches1,matches2,dyGood,dxGood,pos(3)); 
            app.NmatchesEditField.Value = length(pts1);
            app.Mosaic.Nmatches(app.TformEditField.Value) = app.NmatchesEditField.Value;
            app.Mosaic.Tforms(app.TformEditField.Value) = estimateGeometricTransform(pts2,pts1,'similarity');
            drawTransform(app, app.Mosaic.Tforms(app.TformEditField.Value), pts1, pts2, dyGood, dxGood, dMap);
        end

        % Menu selected function: FromDirectoryMenu
        function FromDirectoryMenuSelected(app, event)
            p = uigetdir("");            
            if (ischar(p))
                figure(app.UIFigure) 
                DataDir = p;
            else
                figure(app.UIFigure) 
                return
            end
                       
            app.VideoFrames = []; app.VideoFramesComposites = []; app.VideoFramesGPU = [];            
            ChannelDirs = dir(DataDir);
            app.VideoParameters.nchannels = length(ChannelDirs) - 3;
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Loading Raw Data');
            d.Value = 0;
            
            for i = 1:app.VideoParameters.nchannels
                current_ch_dir = dir([DataDir '/' ChannelDirs(i+2).name]);
                n_frames = length(current_ch_dir) - 2;
                for j = 1:n_frames
                    app.VideoFrames(:,:,i,j) = imread([DataDir '/' ChannelDirs(i+2).name '/' current_ch_dir(j+2).name]);
                    d.Value = double(j-1)/(n_frames*app.VideoParameters.nchannels) + double(i-1)/app.VideoParameters.nchannels;
                end
            end
            
            d.Message = 'Loading Composite Data';
            d.Value = 0;            
            current_ch_dir = dir([DataDir '/' ChannelDirs(end).name]);
            
            for j = 1:(length(current_ch_dir)-2)
                app.VideoFramesComposites(:,:,:,j) = imread([DataDir '/' ChannelDirs(end).name '/' current_ch_dir(j+2).name]);
                d.Value = double(j-1)/(length(current_ch_dir)-2);
            end
            
            close(d);            
            app.VideoFrames = double(app.VideoFrames)/255; app.VideoFramesComposites = double(app.VideoFramesComposites)/255; % For 8-bit images only!
            dataSizeInit(app);                          
            gpuInit(app);
            
            app.Mosaic.Composite = zeros(app.VideoParameters.yRes, app.VideoParameters.xRes, 3);    
            app.VideoParameters.HasData = 1;
            drawFrame(app, app.RegistrationImage, 1);
            registrationEnable(app, 1);            
        end

        % Menu selected function: FromFileMenu
        function FromFileMenuSelected(app, event)
            [filename, path] = uigetfile('*.h5','Load Video Frames from .h5');
            if isequal(filename,0)

            else
                d = uiprogressdlg(app.UIFigure,'Title','Loading Data...','Indeterminate','on');

                try                    
                    frames = h5read(strcat(path,filename),'/Channels');
                    framesC = h5read(strcat(path,filename),'/Composite');                    
                    if ndims(frames) == 4 && ndims(framesC) == 4
                        app.VideoFramesComposites = framesC;
                        app.VideoFrames = frames;
                        dataSizeInit(app);  
                        gpuInit(app)
                        
                        app.Mosaic.Composite = zeros(app.VideoParameters.yRes, app.VideoParameters.xRes, 3);    
                        app.VideoParameters.HasData = 1;
                        drawFrame(app, app.RegistrationImage, 1);
                        registrationEnable(app, 1);                         
                    end
                catch
                    uialert(app.UIFigure,'The selected file was not a valid 4-D frame matrix','Frames Not Loaded');
                end
                close(d);
            end
            figure(app.UIFigure)             
        end

        % Menu selected function: FromHyperViewerMenu
        function FromHyperViewerMenuSelected(app, event)
            % Load raw data from hyperviewer output. 
            p = uigetdir("");            
            if (ischar(p))
                figure(app.UIFigure) 
                DataDir = p;
            else
                figure(app.UIFigure) 
                return
            end
                       
            app.VideoFrames = []; app.VideoFramesComposites = []; app.VideoFramesGPU = [];            
            FrameDirs = dir(DataDir);
            FrameDirs = FrameDirs([FrameDirs.isdir]); % Only get folders
            SortDirs = natsortfiles({FrameDirs.name});
            app.VideoParameters.nframes = length(FrameDirs) - 5;
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Loading Data');
            d.Value = 0;
            
            for i = 1:app.VideoParameters.nframes
                greyChannels = dir(fullfile([DataDir '/' SortDirs{i+2} '/outputGPU'],'basis_image_intensity_map*'));
                j = 1;
                for k = 1:numel(greyChannels)
                    R = regexp(greyChannels(k).name,'(AF610|AF633|AF647|AF660|AF680|AF700|AF750|Cy7|Sulforhodamine-101|Acridine-Orange)','match','once');
                    if R
                        app.VideoFrames(:,:,j,i) = imread([greyChannels(k).folder '/' greyChannels(k).name]);   
                        j = j + 1;
                    end
                end
                composite = dir(fullfile([DataDir '/' SortDirs{i+2} '/outputGPU'],'basis_composite*'));
                app.VideoFramesComposites(:,:,:,i) = imread([composite.folder '/' composite.name]);
                d.Value = double(i-1)/app.VideoParameters.nframes;
            end
                       
            close(d);   
            
            app.VideoParameters.OriginalDataType = 'double';
            if max(app.VideoFrames, [], 'all') > 257
                app.VideoFrames = double(app.VideoFrames)/65535; % uint16
                app.VideoParameters.OriginalDataType = 'uint16';
            elseif max(app.VideoFrames, [], 'all') > 2
                app.VideoFrames = double(app.VideoFrames)/255; % uint8
                app.VideoParameters.OriginalDataType = 'uint8';
            end
            
            app.VideoFramesComposites = double(app.VideoFramesComposites) / max(app.VideoFramesComposites, [], 'all');
            dataSizeInit(app);                          
            gpuInit(app);
            
            app.Mosaic.Composite = zeros(app.VideoParameters.yRes, app.VideoParameters.xRes, 3);    
            app.VideoParameters.HasData = 1;
            drawFrame(app, app.RegistrationImage, 1);
            registrationEnable(app, 1);   
        end

        % Menu selected function: LoadMosaicPathMenu
        function LoadMosaicPathMenuSelected(app, event)
            [filename, path] = uigetfile('*.mat','Load Mosaic Alignments');
            if isequal(filename,0)

            else
                loadedFile = load(strcat(path,filename));
                try
                    if loadedFile.mosaic.IsValidMosaic
                        app.Mosaic = loadedFile.mosaic;
                        app.TformSlider.Limits = [1, app.VideoParameters.nframes-1];
                        tformViewerEnable(app,1);
                        stitchingEnable(app,1);
                    end
                catch
                    uialert(app.UIFigure,'The selected file was not a valid mosaic path generated by this app','Alignments Not Loaded');
                end
            end

            figure(app.UIFigure) 
        end

        % Menu selected function: LoadMosaicImageMenu
        function LoadMosaicImageMenuSelected(app, event)
            [filename,user_canceled] = imgetfile;
            if user_canceled
                
            else
                app.Mosaic.Composite = imread(filename);
                mosaicSmall = imresize(app.Mosaic.Composite,0.1,'nearest');
                imshow(mosaicSmall,'Parent',app.MosaicImage);
                trackingEnable(app,1);
            end
            figure(app.UIFigure)            
        end

        % Menu selected function: SaveRawDataash5Menu
        function SaveRawDataash5MenuSelected(app, event)
            [filename,path] = uiputfile('*.h5','Save Video Frames as .h5', 'VideoFrames.h5');
            if isequal(filename,0) 
                
            else
                if app.VideoParameters.HasData
                    d = uiprogressdlg(app.UIFigure,'Title','Saving Data...','Indeterminate','on');
                    h5create(strcat(path,filename),'/Channels',size(app.VideoFrames));
                    h5create(strcat(path,filename),'/Composite',size(app.VideoFramesComposites));
                    h5write(strcat(path,filename),'/Channels',app.VideoFrames);
                    h5write(strcat(path,filename),'/Composite',app.VideoFramesComposites);      
                    close(d);
                else
                    uialert(app.UIFigure,'No raw data available to save!','Video Frames Not Saved');
                end                
            end
            figure(app.UIFigure)            
        end

        % Menu selected function: SaveMosaicPathMenu
        function SaveMosaicPathMenuSelected(app, event)
            [filename,path] = uiputfile('*.mat','Save Mosaic Alignments', 'MosaicAlignments.mat');
            if isequal(filename,0) 
                
            else
                if isfield(app.Mosaic, 'Tforms')                
                    app.Mosaic.IsValidMosaic = 1;
                    mosaic = app.Mosaic;
                    save(strcat(path,filename), 'mosaic', '-mat');
                else
                    uialert(app.UIFigure,'No alignments have been calculated yet!','Alignments Not Saved');
                end                
            end
            figure(app.UIFigure) 
        end

        % Menu selected function: SaveMosaicImageMenu
        function SaveMosaicImageMenuSelected(app, event)
            [filename,fmt,user_canceled] = imputfile;
            if user_canceled
                
            else
                imwrite(app.Mosaic.Composite, filename, fmt)
                
                if app.MosaickingParameters.IndividualMosaics
                for i = 1:size(app.VideoFrames, 3)
                    imwrite(app.Mosaic.Individuals(:,:,i), [filename(1:end-4) 'Ch' int2str(i) filename(end-3:end)], fmt);
                end
                end
            end
            figure(app.UIFigure) 
        end

        % Menu selected function: XCorrelationMenu
        function XCorrelationMenuSelected(app, event)
            app.SURFFeatureRefinedMenu.Checked = false;
            app.SURFMLESACMenu.Checked = false;
            app.XCorrelationMenu.Checked = true;
            translationSelected(app);
            app.SimilarityMenu.Enable = false;
            app.AffineMenu.Enable = false;
        end

        % Menu selected function: SURFFeatureRefinedMenu
        function SURFFeatureRefinedMenuSelected(app, event)
            app.XCorrelationMenu.Checked = false;
            app.SURFMLESACMenu.Checked = false;
            app.SURFFeatureRefinedMenu.Checked = true;
            app.SimilarityMenu.Enable = true;
            app.AffineMenu.Enable = true;            
        end

        % Menu selected function: SURFMLESACMenu
        function SURFMLESACMenuSelected(app, event)
            app.XCorrelationMenu.Checked = false;
            app.SURFFeatureRefinedMenu.Checked = false;
            app.SURFMLESACMenu.Checked = true;
            app.SimilarityMenu.Enable = true;
            app.AffineMenu.Enable = true;             
        end

        % Menu selected function: TranslationMenu
        function TranslationMenuSelected(app, event)
            translationSelected(app);
        end

        % Menu selected function: SimilarityMenu
        function SimilarityMenuSelected(app, event)
            similaritySelected(app);
        end

        % Menu selected function: AffineMenu
        function AffineMenuSelected(app, event)
            affineSelected(app);
        end

        % Menu selected function: CropDataMenu
        function CropDataMenuSelected(app, event)
            prompt = {'First Frame','Last Frame','xMin','xMax','yMin','yMax'};
            fStart = 1; fEnd = app.VideoParameters.nframes; xMin = 1; xMax = app.VideoParameters.xRes; yMin = 1; yMax = app.VideoParameters.yRes;
            definput = strtrim(cellstr(num2str([fStart;fEnd;xMin;xMax;yMin;yMax]))');
            cropParams = inputdlg(prompt,'Crop Data',1,definput);
            
            if ~isequal(cropParams, {})
                if str2double(cropParams{1}) >= 1 && str2double(cropParams{1}) < app.VideoParameters.nframes
                    fStart = round(str2double(cropParams{1}));
                end
                if str2double(cropParams{2}) > fStart && str2double(cropParams{2}) <= app.VideoParameters.nframes
                    fEnd = round(str2double(cropParams{2}));
                end
                if str2double(cropParams{3}) >= xMin && str2double(cropParams{3}) < xMax
                    xMin = round(str2double(cropParams{3}));
                end
                if str2double(cropParams{4}) > xMin && str2double(cropParams{4}) <= xMax
                    xMax = round(str2double(cropParams{4}));
                end
                if str2double(cropParams{5}) >= yMin && str2double(cropParams{5}) < yMax
                    yMin = round(str2double(cropParams{5}));
                end
                if str2double(cropParams{6}) > yMin && str2double(cropParams{6}) <= yMax
                    yMax = round(str2double(cropParams{6}));
                end

                app.VideoFrames = app.VideoFrames(yMin:yMax, xMin:xMax, :, fStart:fEnd);
                app.VideoFramesGPU = app.VideoFramesGPU(yMin:yMax, xMin:xMax, :, fStart:fEnd);
                app.VideoFramesComposites = app.VideoFramesComposites(yMin:yMax, xMin:xMax, :, fStart:fEnd);            
                dataSizeInit(app);
                
                app.FrameSlider.Limits = [1, app.VideoParameters.nframes];
                app.FrameSlider.Value = 1; app.FrameEditField.Value = 1;
                drawFrame(app, app.RegistrationImage, round(app.FrameSlider.Value));   
            end
            
        end

        % Menu selected function: FlatfieldCorrectionMenu
        function FlatfieldCorrectionMenuSelected(app, event)
            app.VideoParameters.FFMaps = mean(app.VideoFrames, 4);
            app.VideoParameters.FFComp = imgaussfilt(mean(app.VideoParameters.FFMaps, 3));
            
            app.VideoParameters.FFComp = app.VideoParameters.FFComp / mean(app.VideoParameters.FFComp, 'all');
            
            FFFrames = repmat(app.VideoParameters.FFComp, [1 1 size(app.VideoFrames, [3 4])]);
            FFFramesGPU = gpuArray(FFFrames);
            
            % TODO: Normalize frames again to maximum 
            app.VideoFramesGPU = app.VideoFramesGPU ./ FFFramesGPU;
            app.VideoFrames = app.VideoFrames ./ FFFrames;
            FFFramesComp = repmat(app.VideoParameters.FFComp, [1 1 size(app.VideoFramesComposites, [3 4])]);
            app.VideoFramesComposites = app.VideoFramesComposites ./ FFFramesComp;
        end

        % Menu selected function: OverlayMenu
        function OverlayMenuSelected(app, event)
            app.OverlayMenu.Checked = true;
            app.MontageMenu.Checked = false;
        end

        % Menu selected function: MontageMenu
        function MontageMenuSelected(app, event)
            app.MontageMenu.Checked = true;
            app.OverlayMenu.Checked = false;            
        end

        % Menu selected function: GraphCutMenu
        function GraphCutMenuSelected(app, event)
            app.GraphCutMenu.Checked = true;
            app.LayeringMenu.Checked = false;               
        end

        % Menu selected function: LayeringMenu
        function LayeringMenuSelected(app, event)
            app.LayeringMenu.Checked = true;      
            app.GraphCutMenu.Checked = false;
        end

        % Menu selected function: CalculateMenu
        function CalculateMenuSelected(app, event)
            mosaicInit(app);
            blender = vision.AlphaBlender('Operation', 'Binary mask', ...
                'MaskSource', 'Input port');  
            
            app.Mosaic.dydxTform(1,:) = [0 0];
            tic;
            for i = 1:(app.VideoParameters.nframes-1)
                app.TformSlider.Enable = 0;            
                
                [dyCorr, dxCorr, ~, ~] = mchannel_nxcorr_reg_GPU(...
                    app.VideoFramesGPU(:,:,:,i), ...
                    app.VideoFramesGPU(:,:,:,i+1));
                app.Mosaic.dydxCorr(i,:)  = [gather(dyCorr); gather(dxCorr)];
                
                %% If using 2D-cross-correlation %%
                if app.XCorrelationMenu.Checked
                    app.Mosaic.Tforms(i) = affine2d(double([1 0 0; 0 1 0; dxCorr dyCorr 1]));
                    app.Mosaic.dydxGood(i,:) = [dyCorr, dxCorr]; 
                    app.Mosaic.dydxTform(i,:) = [app.Mosaic.Tforms(i).T(3,2) app.Mosaic.Tforms(i).T(3,1)];
                    
                %% If using SURF features with cost function %%
                elseif app.SURFFeatureRefinedMenu.Checked          
                    if i > 1
                        [dySURFs, dxSURFs, peakVals, matches1, matches2, ~, oldMatches] = mchannel_SURF_reg_iterative(...
                            app.VideoFrames(:,:,:,i+1), oldMatches); 
                        app.Mosaic.dydxSURFs(i,:,:) = [dySURFs'; dxSURFs'; peakVals'];
                        
                        [dyGood,dxGood] = featureGroupPicker(app.Mosaic.dydxSURFs(i,:,:),...
                            app.Mosaic.dydxTform(i-1,:), ...
                            app.Mosaic.dydxCorr(i,:), []);
                    else
                        [dySURFs, dxSURFs, peakVals, matches1, matches2, ~, oldMatches] = mchannel_SURF_reg(...
                            app.VideoFrames(:,:,:,i), ...
                            app.VideoFrames(:,:,:,i+1));  
                        app.Mosaic.dydxSURFs(i,:,:) = [dySURFs'; dxSURFs'; peakVals'];
                   
                        [dyGood,dxGood] = featureGroupPicker(app.Mosaic.dydxSURFs(i,:,:),...
                            app.Mosaic.dydxCorr(i,:), ...
                            app.Mosaic.dydxCorr(i,:), []);
                    end    
                    
                    app.Mosaic.dydxGood(i,:) = [dyGood, dxGood];
                    [refinedMatches1,refinedMatches2] = refineMatches(matches1,matches2,dyGood,dxGood,app.MosaickingParameters.FeatureSpreadTolerance);

                    switch app.MosaickingParameters.matrixtype
                        case {'Similarity', 'Affine'}
                            app.Mosaic.Tforms(i) = estimateGeometricTransform(refinedMatches2,refinedMatches1, app.MosaickingParameters.matrixtype);
                        case 'Translation'
                            [~, inlier2, inlier1] = estimateGeometricTransform(refinedMatches2,refinedMatches1, 'Similarity');
                            dxdy = mean(inlier1.Location - inlier2.Location, 1);
                            app.Mosaic.Tforms(i) = affine2d(double([1 0 0; 0 1 0; dxdy(1) dxdy(2) 1])); 
                    end

                    app.Mosaic.dydxTform(i,:)  = [app.Mosaic.Tforms(i).T(3,2) app.Mosaic.Tforms(i).T(3,1)];
                    app.Mosaic.Nmatches(i) = length(refinedMatches1);
                    
                %% If using SURF features with MLESAC only %%
                elseif app.SURFMLESACMenu.Checked
                    % Make single-channel analog                    
                    if i > 1
                        [dySURFs, dxSURFs, peakVals, matches1, matches2, ~, oldMatches] = mchannel_SURF_reg_iterative(...
                            app.VideoFrames(:,:,:,i+1), oldMatches); 
                        app.Mosaic.dydxSURFs(i,:,:) = [dySURFs'; dxSURFs'; peakVals'];
                    else
                        [dySURFs, dxSURFs, peakVals, matches1, matches2, ~, oldMatches] = mchannel_SURF_reg(...
                            app.VideoFrames(:,:,:,i), ...
                            app.VideoFrames(:,:,:,i+1));  
                        app.Mosaic.dydxSURFs(i,:,:) = [dySURFs'; dxSURFs'; peakVals'];
                    end    
                    
                    app.Mosaic.dydxGood(i,:) = [dyCorr, dxCorr]; 

                    switch app.MosaickingParameters.matrixtype
                        case {'Similarity', 'Affine'}
                            %[refinedMatches1,refinedMatches2] = refineMatches(matches1,matches2,dyGood,dxGood,app.MosaickingParameters.FeatureSpreadTolerance);
                            app.Mosaic.Tforms(i) = estimateGeometricTransform(matches2,matches1, app.MosaickingParameters.matrixtype);
                        case 'Translation'
                            [~, inlier2, inlier1] = estimateGeometricTransform(matches2,matches1, 'Similarity');
                            dxdy = mean(inlier1.Location - inlier2.Location, 1);
                            app.Mosaic.Tforms(i) = affine2d(double([1 0 0; 0 1 0; dxdy(1) dxdy(2) 1]));
                    end

                    app.Mosaic.dydxTform(i,:)  = [app.Mosaic.Tforms(i).T(3,2) app.Mosaic.Tforms(i).T(3,1)];
                    app.Mosaic.Nmatches(i) = length(matches1);                  
                end

                [xlims, ylims] = outputLimits(app.Mosaic.Tforms(i), ...
                    [1 app.VideoParameters.xRes], ...
                    [1 app.VideoParameters.yRes]);
                xMin = min([1; xlims(:)]);
                xMax = max([app.VideoParameters.xRes; xlims(:)]);
                yMin = min([1; ylims(:)]);
                yMax = max([app.VideoParameters.yRes; ylims(:)]);
                                                
                panoramaView = imref2d([round(yMax-yMin) round(xMax-xMin)], [xMin xMax], [yMin yMax]);            
                Mt = imwarp(app.VideoFramesComposites(:,:,...
                    :,i), affine2d, 'OutputView', panoramaView); 
                I2tc = imwarp(app.VideoFramesComposites(:,:,...
                    :,i+1), app.Mosaic.Tforms(i), 'OutputView', panoramaView); 
                
                mask = imwarp(true(app.VideoParameters.yRes, app.VideoParameters.xRes), app.Mosaic.Tforms(i), 'OutputView', panoramaView);
                fPair = step(blender, Mt, I2tc, mask);         
                
                imshow(fPair,'Parent',app.RegistrationImage);
                drawnow;                      
            end
            t = toc;
            disp(t);
            timeperframe = t / app.VideoParameters.nframes;
            disp(timeperframe);
            app.TformSlider.Enable = 0;
            tformViewerEnable(app,1);
            stitchingEnable(app,1);
            app.TformSlider.Limits = [1, app.VideoParameters.nframes-1];         
        end

        % Menu selected function: AutoFixAlignmentsMenu
        function AutoFixAlignmentsMenuSelected(app, event)
            tic
            velocities = zeros([length(app.Mosaic.Tforms) 2]);
            for i = 1:length(app.Mosaic.Tforms)
                velocities(i, :) = app.Mosaic.Tforms(i).T(3, 1:2);
            end
            
            % Find accelerations
            accelerations = diff(velocities, 1, 1);
            acceleration_mag = sqrt(accelerations(:, 1).^2 + accelerations(:, 2).^2);
            % Find outlier accelerations
            suspect_alignments = find(isoutlier(acceleration_mag)); %% Note - I don't know if the indices of this array match up to the original ones. 
            
            % Ask user how they want to fix the alignments            
            fixmthd = questdlg('Choose an alignment matrix constraint identifier', ...
        	'Auto-Fix Alignment Method', ...
        	'Translation','Similarity','Affine','Translation');
            
            msgstring = sprintf("Fixing %d suspect alignments out of %d", length(suspect_alignments), length(app.Mosaic.Tforms));
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message',msgstring);
            d.Value = 0;
     
            for j = 1:length(suspect_alignments)
                i = suspect_alignments(j);                
                if i == 1
                    continue
                end
                
                [dySURFs, dxSURFs, peakVals, matches1, matches2, ~, ~] = mchannel_SURF_reg(...
                    app.VideoFrames(:,:,:,i), ...
                    app.VideoFrames(:,:,:,i+1));  
                
                app.Mosaic.dydxSURFs(i,:,:) = [dySURFs'; dxSURFs'; peakVals'];

                if ismember(i+1, suspect_alignments) || i+1 > length(app.Mosaic.dydxTform)  % The next dydx is suspect too, cannot use
                    [dyGood,dxGood] = featureGroupPicker(app.Mosaic.dydxSURFs(i,:,:),...
                        app.Mosaic.dydxTform(i-1,:), ...
                        [], []);                    
                else
                    [dyGood,dxGood] = featureGroupPicker(app.Mosaic.dydxSURFs(i,:,:),...
                        app.Mosaic.dydxTform(i-1,:), ...
                        [], app.Mosaic.dydxTform(i+1,:)); % dydxTform(i+1) = dydxNext
                end
   
                app.Mosaic.dydxGood(i,:) = [dyGood, dxGood];
           
                switch fixmthd
                    case {'Similarity', 'Affine'}
                        [refinedMatches1,refinedMatches2] = refineMatches(matches1,matches2,dyGood,dxGood,app.MosaickingParameters.FeatureSpreadTolerance);
                        app.Mosaic.Tforms(i) = estimateGeometricTransform(refinedMatches2,refinedMatches1, fixmthd);
                        app.Mosaic.Nmatches(i) = length(refinedMatches1);  
                    case 'Translation'
                        app.Mosaic.Tforms(i) = affine2d(double([1 0 0; 0 1 0; dxGood dyGood 1]));
                end

                app.Mosaic.dydxTform(i,:) = [app.Mosaic.Tforms(i).T(3,2) app.Mosaic.Tforms(i).T(3,1)];
                d.Value = j/length(suspect_alignments);                     
            end                   
            close(d);
            t = toc;
            disp(t);
            timeperframe = t / app.VideoParameters.nframes;
            disp(timeperframe);
        end

        % Menu selected function: StitchMosaicMenu
        function StitchMosaicMenuSelected(app, event)
            stitchInit(app); % Enable viewing of mosaic image
            app.PathMenu.Checked = false;
            app.MosaicMenu.Enable = true;
            app.MosaicMenu.Checked = true;
            
            answer = questdlg('Stitch individual channel mosaics as well?', 'Individal Channels', 'Yes', 'No', 'No');
            switch answer 
                case 'Yes'
                    app.MosaickingParameters.IndividualMosaics = true;
                case {'No', ''}
                    app.MosaickingParameters.IndividualMosaics = false;
            end
            app.Mosaic.GlobalTforms = app.Mosaic.Tforms;
            [xlims(1,:), ylims(1,:)] = outputLimits(app.Mosaic.Tforms(1), [1 app.VideoParameters.xRes], [1 app.VideoParameters.yRes]); 
            
            for i = 2:numel(app.Mosaic.Tforms)
                app.Mosaic.GlobalTforms(i).T = app.Mosaic.GlobalTforms(i).T * app.Mosaic.GlobalTforms(i-1).T;
                [xlims(i,:), ylims(i,:)] = outputLimits(app.Mosaic.GlobalTforms(i), [1 app.VideoParameters.xRes], [1 app.VideoParameters.yRes]); 
            end
             
            xMin = min([1; xlims(:)]); xMax = max([app.VideoParameters.xRes; xlims(:)]);            
            yMin = min([1; ylims(:)]); yMax = max([app.VideoParameters.yRes; ylims(:)]);
            width  = round(xMax - xMin); height = round(yMax - yMin); pad = 10; padm = pad-1;           
            app.Mosaic.Composite = zeros([height+4*pad width+4*pad 3]);
            panoramaView = imref2d([height+4*pad width+4*pad], [xMin-2*pad xMax+2*pad], [yMin-2*pad yMax+2*pad]);
            
            mosaicSmall = imresize(app.Mosaic.Composite,0.1,'nearest');
            panorama = imshow(mosaicSmall,'Parent',app.MosaicImage);
            
            blender = vision.AlphaBlender('Operation', 'Binary mask', ...
                'MaskSource', 'Input port');
            
            warpedImage = imwarp(app.VideoFramesComposites(:,:,:,1), affine2d, 'OutputView', panoramaView);  
 
            mask = imwarp(true(app.VideoParameters.yRes, app.VideoParameters.xRes), affine2d, 'OutputView', panoramaView);
            app.Mosaic.Composite = step(blender, app.Mosaic.Composite, warpedImage, mask);
            
            if app.MosaickingParameters.IndividualMosaics
                app.Mosaic.Individuals = zeros([height+4*pad width+4*pad size(app.VideoFrames,3)]);
                warpedImageIndividuals = imwarp(app.VideoFrames(:,:,:,1), affine2d, 'OutputView', panoramaView); 
                app.Mosaic.Individuals = step(blender, app.Mosaic.Individuals, warpedImageIndividuals, mask);
            end
            
            for i = 1:numel(app.Mosaic.Tforms)-1
                 % TODO: Imwarp is the slowest part of the function. Can be
                 % done with GPU or something?
                warpedImage = imwarp(app.VideoFramesComposites(:,:,...
                    :,i+1), app.Mosaic.GlobalTforms(i), 'OutputView', panoramaView);    
                if app.MosaickingParameters.IndividualMosaics
                warpedImageIndividuals = imwarp(app.VideoFrames(:,:,...
                :,i+1), app.Mosaic.GlobalTforms(i), 'OutputView', panoramaView);  
                end
                
                if app.GraphCutMenu.Checked
                    [I, J] = worldToSubscript(panoramaView,xlims(i,:),ylims(i,:));
                    mSlice = app.Mosaic.Composite(I(1)-pad:I(2)+pad,J(1)-pad:J(2)+pad,:); 
                    fSlice = warpedImage(I(1):I(2),J(1):J(2),:);
                    fSlice = padarray(fSlice, [pad pad], 0, 'both');
                    stitch = double(stitchImages(mSlice, fSlice))/255;                    
                    app.Mosaic.Composite(I(1)-padm:I(2)+padm,J(1)-padm:J(2)+padm,:) = stitch(2:end-1,2:end-1,:); 
                    
                    if app.MosaickingParameters.IndividualMosaics
                    mSlice = app.Mosaic.Individuals(I(1)-pad:I(2)+pad,J(1)-pad:J(2)+pad,:); 
                    fSlice = warpedImageIndividuals(I(1):I(2),J(1):J(2),:);
                    fSlice = padarray(fSlice, [pad pad], 0, 'both');
                    stitch = double(stitchImages(mSlice, fSlice))/255;                    
                    app.Mosaic.Individuals(I(1)-padm:I(2)+padm,J(1)-padm:J(2)+padm,:) = stitch(2:end-1,2:end-1,:);     
                    end
                    
                elseif app.LayeringMenu.Checked
                    mask = imwarp(true(app.VideoParameters.yRes, app.VideoParameters.xRes), app.Mosaic.GlobalTforms(i), 'OutputView', panoramaView);
                    app.Mosaic.Composite = step(blender, app.Mosaic.Composite, warpedImage, mask);
                    if app.MosaickingParameters.IndividualMosaics
                    app.Mosaic.Individuals = step(blender, app.Mosaic.Individuals, warpedImageIndividuals, mask);
                    end
                end                
                mosaicSmall = imresize(app.Mosaic.Composite,0.1,'nearest');
                set(panorama, 'CData', mosaicSmall); drawnow; % 0.06 ms or 16 Hz
            end
            trackingEnable(app,1);
            
        end

        % Menu selected function: PathMenu
        function PathMenuSelected(app, event)
            app.PathMenu.Checked = true;
            app.MosaicMenu.Checked = false;
            graphMosaicPath(app)
        end

        % Menu selected function: MosaicMenu
        function MosaicMenuSelected(app, event)
            app.PathMenu.Checked = false;
            app.MosaicMenu.Checked = true;
            mosaicSmall = imresize(app.Mosaic.Composite,0.1,'nearest');
            imshow(mosaicSmall,'Parent',app.MosaicImage); drawnow;        
        end

        % Menu selected function: FindaRandomFrameMenu
        function FindaRandomFrameMenuSelected(app, event)
            try
                fID = randi([1 app.VideoParameters.nframes]);  
                mosaicSmall = imresize(app.Mosaic.Composite,app.Tracking.Scale,'nearest');
                frameSmall = imresize(app.VideoFramesComposites(:,:,:,fID) ...
                ,app.Tracking.Scale,'nearest');
            catch
                uialert(app.UIFigure,'Missing raw data or mosaic image','Error');
                return
            end
            [~, ~, matches1, matches2, ~] = position_tracker_SURF(frameSmall, app.Tracking.MosaicSURFPoints, app.Tracking.Tol);            
            figure(1); imshow(frameSmall);  

            Tform = estimateGeometricTransform(matches1, matches2, "similarity");
                
            x = [0 app.VideoParameters.xRes*app.Tracking.Scale app.VideoParameters.xRes*app.Tracking.Scale 0];
            y = [0 0 app.VideoParameters.yRes*app.Tracking.Scale app.VideoParameters.yRes*app.Tracking.Scale];
            [xt,yt] = transformPointsForward(Tform,x,y);
            mosaicWithFrame = insertShape(mosaicSmall,'FilledPolygon',[xt(1) yt(1) xt(2) yt(2) xt(3) yt(3) xt(4) yt(4)],"Opacity",0.4,"Color","white");
            
            mosaicWithFrame = imresize(mosaicWithFrame, 0.1/app.Tracking.Scale, 'nearest');
            imshow(mosaicWithFrame,'Parent',app.MosaicImage);
        end

        % Menu selected function: TrackingParametersMenu
        function TrackingParametersMenuSelected(app, event)
            prompt = {'Downsampling Scale','Displacement Tolerance'};
            Scale = app.Tracking.Scale;
            Tol = app.Tracking.Tol;
            definput = strtrim(cellstr(num2str([Scale;Tol]))');
            scaleParams = inputdlg(prompt,'Tracking Parameters',1,definput);
            
            if ~isequal(scaleParams, {})
                if str2double(scaleParams{1}) >= 0 && str2double(scaleParams{1}) < 1
                    Scale = str2double(scaleParams{1});
                end
                if str2double(scaleParams{2}) >= 0 
                    Tol = str2double(scaleParams{2});
                end
                app.Tracking.Scale = Scale;
                app.Tracking.Tol = Tol;
            end
            trackingEnable(app, 1);
        end

        % Menu selected function: ThisFramePairMenu
        function ThisFramePairMenuSelected(app, event)
            msgbox('Use the frame-pair montage to create at least three matches. Left click left-hand image to start a match, right click on right-hand image to finish it. Middle click to end.'...
                ,'Manual Match','help');
            Fig = figure('Name', 'Manual Match');
            imshowpair(app.VideoFramesComposites(:,:,:,round(app.TformEditField.Value))...
                ,app.VideoFramesComposites(:,:,:,round(app.TformEditField.Value)+1), 'montage');
            
            hold on;
            k = 0;
            while 1 % Code here is unoptomized for length
                [xi, yi, but] = ginput(1);
                if isequal(but, 2)             
                    break % Close if middle button
                elseif isequal (but, 3)
                    continue % Restart if right button
                end
                k = k + 1;
                pts1(k,:) = [xi, yi];
                ptleft = plot(xi, yi, 'ro');
                [xj, yj, but] = ginput(1);
                delete(ptleft);
                if isequal (but, 1) % Restart if left button
                    k = k - 1; 
                    continue
                end 
                pts2(k,:) = [xj, yj];
                plot(xi, yi, 'ro'); plot(xj, yj, 'g+'); plot([xi xj], [yi yj], 'y');
            end
            
            hold off
            close(Fig);
            pts2(:,1) = pts2(:,1) - app.VideoParameters.xRes; % Pts2 x values are too big by the width of the image
            
            pts1 = SURFPoints(pts1); pts2 = SURFPoints(pts2);
            
            selection = uiconfirm(app.UIFigure, 'Select transform method below.' , 'Manual Match',...
           'Options',{'Translation','Similarity','Affine','Cancel'},...
           'DefaultOption',1,'CancelOption',4);
            
            switch selection
                case 'Translation'
                    app.Mosaic.Tforms(app.TformEditField.Value) = ...
                        affine2d(double([1 0 0; 0 1 0; mean(pts1.Location-pts2.Location, 1) 1]));
                case 'Similarity'
                    app.Mosaic.Tforms(app.TformEditField.Value) = fitgeotrans(pts1,pts2,'nonreflectivesimilarity');
                case 'Affine'
                    app.Mosaic.Tforms(app.TformEditField.Value) = fitgeotrans(pts1,pts2,'affine');
                case 'Cancel'
                    return
            end
        end

        % Menu selected function: AllFramePairsMenu
        function AllFramePairsMenuSelected(app, event)
            msgbox('Use the frame-pair montage to create at least three matches. Left click left-hand image to start a match, right click on right-hand image to finish it. Middle click to end.'...
                ,'Manual Match','help');

            selection = uiconfirm(app.UIFigure, 'Select transform method below.' , 'Manual Match',...
           'Options',{'Translation','Similarity','Affine','Cancel'},...
           'DefaultOption',1,'CancelOption',4);

            for i = 1:app.VideoParameters.nframes-1
            Fig = figure('Name', 'Manual Match');
            imshowpair(app.VideoFramesComposites(:,:,:,i)...
                ,app.VideoFramesComposites(:,:,:,i+1), 'montage');
            
            hold on;
            k = 0;
            while 1 % Code here is unoptomized for length
                [xi, yi, but] = ginput(1);
                if isequal(but, 2)             
                    break % Close if middle button
                elseif isequal (but, 3)
                    continue % Restart if right button
                end
                k = k + 1;
                pts1(k,:) = [xi, yi];
                ptleft = plot(xi, yi, 'ro');
                [xj, yj, but] = ginput(1);
                delete(ptleft);
                if isequal (but, 1) % Restart if left button
                    k = k - 1; 
                    continue
                end 
                pts2(k,:) = [xj, yj];
                plot(xi, yi, 'ro'); plot(xj, yj, 'g+'); plot([xi xj], [yi yj], 'y');
            end
            
            hold off
            close(Fig);
            pts2(:,1) = pts2(:,1) - app.VideoParameters.xRes; % Pts2 x values are too big by the width of the image
            
            pts1SURF = SURFPoints(pts1); pts2SURF = SURFPoints(pts2);


            switch selection
                case 'Translation'
                    app.Mosaic.Tforms(i) = ...
                        affine2d(double([1 0 0; 0 1 0; mean(pts1SURF.Location-pts2SURF.Location, 1) 1]));
                case 'Similarity'
                    app.Mosaic.Tforms(i) = fitgeotrans(pts1SURF,pts2SURF,'nonreflectivesimilarity');
                case 'Affine'
                    app.Mosaic.Tforms(i) = fitgeotrans(pts1SURF,pts2SURF,'affine');
                case 'Cancel'
                    return
            end  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MultiMosaic App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadRawDataMenu = uimenu(app.FileMenu);
            app.LoadRawDataMenu.Text = 'Load Raw Data';
            app.FromDirectoryMenu = uimenu(app.LoadRawDataMenu);
            app.FromDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @FromDirectoryMenuSelected, true);
            app.FromDirectoryMenu.Text = '...From Directory';
            app.FromFileMenu = uimenu(app.LoadRawDataMenu);
            app.FromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @FromFileMenuSelected, true);
            app.FromFileMenu.Text = '...From File';
            app.FromHyperViewerMenu = uimenu(app.LoadRawDataMenu);
            app.FromHyperViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @FromHyperViewerMenuSelected, true);
            app.FromHyperViewerMenu.Text = '...From HyperViewer';
            app.LoadMosaicPathMenu = uimenu(app.FileMenu);
            app.LoadMosaicPathMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMosaicPathMenuSelected, true);
            app.LoadMosaicPathMenu.Text = 'Load Mosaic Path';
            app.LoadMosaicImageMenu = uimenu(app.FileMenu);
            app.LoadMosaicImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMosaicImageMenuSelected, true);
            app.LoadMosaicImageMenu.Text = 'Load Mosaic Image';
            app.SaveRawDataash5Menu = uimenu(app.FileMenu);
            app.SaveRawDataash5Menu.MenuSelectedFcn = createCallbackFcn(app, @SaveRawDataash5MenuSelected, true);
            app.SaveRawDataash5Menu.Text = 'Save Raw Data as .h5';
            app.SaveMosaicPathMenu = uimenu(app.FileMenu);
            app.SaveMosaicPathMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMosaicPathMenuSelected, true);
            app.SaveMosaicPathMenu.Text = 'Save Mosaic Path';
            app.SaveMosaicImageMenu = uimenu(app.FileMenu);
            app.SaveMosaicImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMosaicImageMenuSelected, true);
            app.SaveMosaicImageMenu.Text = 'Save Mosaic Image';
            app.RegistrationMenu = uimenu(app.UIFigure);
            app.RegistrationMenu.Text = 'Registration';
            app.CropDataMenu = uimenu(app.RegistrationMenu);
            app.CropDataMenu.MenuSelectedFcn = createCallbackFcn(app, @CropDataMenuSelected, true);
            app.CropDataMenu.Text = 'Crop Data';
            app.FlatfieldCorrectionMenu = uimenu(app.RegistrationMenu);
            app.FlatfieldCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @FlatfieldCorrectionMenuSelected, true);
            app.FlatfieldCorrectionMenu.Text = 'Flatfield Correction';
            app.AlignmentKernelMenu = uimenu(app.RegistrationMenu);
            app.AlignmentKernelMenu.Text = 'Alignment Kernel';
            app.XCorrelationMenu = uimenu(app.AlignmentKernelMenu);
            app.XCorrelationMenu.MenuSelectedFcn = createCallbackFcn(app, @XCorrelationMenuSelected, true);
            app.XCorrelationMenu.Checked = 'on';
            app.XCorrelationMenu.Text = 'X-Correlation';
            app.SURFFeatureRefinedMenu = uimenu(app.AlignmentKernelMenu);
            app.SURFFeatureRefinedMenu.MenuSelectedFcn = createCallbackFcn(app, @SURFFeatureRefinedMenuSelected, true);
            app.SURFFeatureRefinedMenu.Text = 'SURF Feature Refined';
            app.SURFMLESACMenu = uimenu(app.AlignmentKernelMenu);
            app.SURFMLESACMenu.MenuSelectedFcn = createCallbackFcn(app, @SURFMLESACMenuSelected, true);
            app.SURFMLESACMenu.Text = 'SURF / MLESAC';
            app.MatrixTypeMenu = uimenu(app.RegistrationMenu);
            app.MatrixTypeMenu.Text = 'Matrix Type';
            app.TranslationMenu = uimenu(app.MatrixTypeMenu);
            app.TranslationMenu.MenuSelectedFcn = createCallbackFcn(app, @TranslationMenuSelected, true);
            app.TranslationMenu.Checked = 'on';
            app.TranslationMenu.Text = 'Translation';
            app.SimilarityMenu = uimenu(app.MatrixTypeMenu);
            app.SimilarityMenu.MenuSelectedFcn = createCallbackFcn(app, @SimilarityMenuSelected, true);
            app.SimilarityMenu.Enable = 'off';
            app.SimilarityMenu.Text = 'Similarity';
            app.AffineMenu = uimenu(app.MatrixTypeMenu);
            app.AffineMenu.MenuSelectedFcn = createCallbackFcn(app, @AffineMenuSelected, true);
            app.AffineMenu.Enable = 'off';
            app.AffineMenu.Text = 'Affine';
            app.CalculateMenu = uimenu(app.RegistrationMenu);
            app.CalculateMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateMenuSelected, true);
            app.CalculateMenu.Separator = 'on';
            app.CalculateMenu.Text = 'Calculate';
            app.AlignmentMenu = uimenu(app.UIFigure);
            app.AlignmentMenu.Text = 'Alignment';
            app.FramePairViewMenu = uimenu(app.AlignmentMenu);
            app.FramePairViewMenu.Text = 'Frame-Pair View';
            app.OverlayMenu = uimenu(app.FramePairViewMenu);
            app.OverlayMenu.MenuSelectedFcn = createCallbackFcn(app, @OverlayMenuSelected, true);
            app.OverlayMenu.Checked = 'on';
            app.OverlayMenu.Text = 'Overlay';
            app.MontageMenu = uimenu(app.FramePairViewMenu);
            app.MontageMenu.MenuSelectedFcn = createCallbackFcn(app, @MontageMenuSelected, true);
            app.MontageMenu.Text = 'Montage';
            app.ShowMatchedFeaturesMenu = uimenu(app.AlignmentMenu);
            app.ShowMatchedFeaturesMenu.Text = 'Show Matched Features';
            app.AutoFixAlignmentsMenu = uimenu(app.AlignmentMenu);
            app.AutoFixAlignmentsMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoFixAlignmentsMenuSelected, true);
            app.AutoFixAlignmentsMenu.Text = 'Auto-Fix Alignments';
            app.ManualMatchMenu = uimenu(app.AlignmentMenu);
            app.ManualMatchMenu.Text = 'Manual Match...';
            app.ThisFramePairMenu = uimenu(app.ManualMatchMenu);
            app.ThisFramePairMenu.MenuSelectedFcn = createCallbackFcn(app, @ThisFramePairMenuSelected, true);
            app.ThisFramePairMenu.Text = '...This Frame-Pair';
            app.AllFramePairsMenu = uimenu(app.ManualMatchMenu);
            app.AllFramePairsMenu.MenuSelectedFcn = createCallbackFcn(app, @AllFramePairsMenuSelected, true);
            app.AllFramePairsMenu.Text = '...All Frame-Pairs';
            app.StitchingMenu = uimenu(app.UIFigure);
            app.StitchingMenu.Text = 'Stitching';
            app.ViewMenu = uimenu(app.StitchingMenu);
            app.ViewMenu.Text = 'View';
            app.PathMenu = uimenu(app.ViewMenu);
            app.PathMenu.MenuSelectedFcn = createCallbackFcn(app, @PathMenuSelected, true);
            app.PathMenu.Checked = 'on';
            app.PathMenu.Text = '...Path';
            app.MosaicMenu = uimenu(app.ViewMenu);
            app.MosaicMenu.MenuSelectedFcn = createCallbackFcn(app, @MosaicMenuSelected, true);
            app.MosaicMenu.Enable = 'off';
            app.MosaicMenu.Text = '...Mosaic';
            app.StitchingTypeMenu = uimenu(app.StitchingMenu);
            app.StitchingTypeMenu.Text = 'Stitching Type';
            app.GraphCutMenu = uimenu(app.StitchingTypeMenu);
            app.GraphCutMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphCutMenuSelected, true);
            app.GraphCutMenu.Checked = 'on';
            app.GraphCutMenu.Text = 'Graph-Cut';
            app.LayeringMenu = uimenu(app.StitchingTypeMenu);
            app.LayeringMenu.MenuSelectedFcn = createCallbackFcn(app, @LayeringMenuSelected, true);
            app.LayeringMenu.Text = 'Layering';
            app.StitchMosaicMenu = uimenu(app.StitchingMenu);
            app.StitchMosaicMenu.MenuSelectedFcn = createCallbackFcn(app, @StitchMosaicMenuSelected, true);
            app.StitchMosaicMenu.Separator = 'on';
            app.StitchMosaicMenu.Text = 'Stitch Mosaic';
            app.TrackingMenu = uimenu(app.UIFigure);
            app.TrackingMenu.Text = 'Tracking';
            app.TrackingParametersMenu = uimenu(app.TrackingMenu);
            app.TrackingParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackingParametersMenuSelected, true);
            app.TrackingParametersMenu.Text = 'Tracking Parameters';
            app.FindaRandomFrameMenu = uimenu(app.TrackingMenu);
            app.FindaRandomFrameMenu.MenuSelectedFcn = createCallbackFcn(app, @FindaRandomFrameMenuSelected, true);
            app.FindaRandomFrameMenu.Separator = 'on';
            app.FindaRandomFrameMenu.Text = 'Find a Random Frame';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataandRegistrationTab = uitab(app.TabGroup);
            app.DataandRegistrationTab.Title = 'Data and Registration';
            app.RegistrationImage = uiaxes(app.DataandRegistrationTab);
            app.RegistrationImage.XTick = [];
            app.RegistrationImage.YTick = [];
            app.FrameSlider = uislider(app.DataandRegistrationTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameEditFieldLabel = uilabel(app.DataandRegistrationTab);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.DataandRegistrationTab, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.AlignmentEditorTab = uitab(app.TabGroup);
            app.AlignmentEditorTab.Title = 'Alignment Editor';
            app.TransformViewer = uiaxes(app.AlignmentEditorTab);
            title(app.TransformViewer, 'Transformation Visualization')
            app.TransformViewer.YDir = 'reverse';
            app.FramePairViewer = uiaxes(app.AlignmentEditorTab);
            title(app.FramePairViewer, 'Frame-Pair Montage')
            app.FramePairViewer.XTick = [];
            app.FramePairViewer.YTick = [];
            app.MatchDensityViewer = uiaxes(app.AlignmentEditorTab);
            title(app.MatchDensityViewer, 'Matched Point Density')
            app.MatchDensityViewer.YDir = 'reverse';
            app.TformSlider = uislider(app.AlignmentEditorTab);
            app.TformSlider.ValueChangingFcn = createCallbackFcn(app, @TformSliderValueChanging, true);
            app.TformEditFieldLabel = uilabel(app.AlignmentEditorTab);
            app.TformEditFieldLabel.Text = 'Tform';
            app.TformEditField = uieditfield(app.AlignmentEditorTab, 'numeric');
            app.TformEditField.ValueChangedFcn = createCallbackFcn(app, @TformEditFieldValueChanged, true);
            app.TformEditField.Value = 1;
            app.AffineMatrixViewer = uitable(app.AlignmentEditorTab);
            app.AffineMatrixViewer.ColumnName = {''};
            app.AffineMatrixViewer.RowName = {''};
            app.TransformMatrixLabel = uilabel(app.AlignmentEditorTab);
            app.TransformMatrixLabel.Text = 'Transform Matrix';
            app.ShowMatchedFeaturesButton = uibutton(app.AlignmentEditorTab, 'push');
            app.ShowMatchedFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMatchedFeaturesButtonPushed, true);
            app.ShowMatchedFeaturesButton.Text = 'Show Matched Features';
            app.ReCalculateTransformButton = uibutton(app.AlignmentEditorTab, 'push');
            app.ReCalculateTransformButton.ButtonPushedFcn = createCallbackFcn(app, @ReCalculateTransformButtonPushed, true);
            app.ReCalculateTransformButton.Text = 'Re-Calculate Transform';
            app.NmatchesEditFieldLabel = uilabel(app.AlignmentEditorTab);
            app.NmatchesEditFieldLabel.Text = 'N matches';
            app.NmatchesEditField = uieditfield(app.AlignmentEditorTab, 'numeric');
            app.NmatchesEditField.Editable = 'off';
            app.NmatchesEditField.Tooltip = {'Number of feature matches'};
            app.NmatchesEditField.Value = 1;
            app.TraceEditFieldLabel = uilabel(app.AlignmentEditorTab);
            app.TraceEditFieldLabel.Text = 'Trace';
            app.TraceEditField = uieditfield(app.AlignmentEditorTab, 'numeric');
            app.TraceEditField.Value = 1.5;
            app.StitchingandTrackingTab = uitab(app.TabGroup);
            app.StitchingandTrackingTab.Title = 'Stitching and Tracking';
            app.MosaicImage = uiaxes(app.StitchingandTrackingTab);
            app.MosaicImage.YDir = 'reverse';
            app.MosaicImage.XTick = [];
            app.MosaicImage.XTickLabel = {};
            app.MosaicImage.YTick = [];
            app.MosaicImage.YTickLabel = {};
            app.MosaicImage.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiMosaic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end